classdef radar_uart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        openserialButton         matlab.ui.control.Button
        COMDropDownLabel         matlab.ui.control.Label
        COMDropDown              matlab.ui.control.DropDown
        TextArea                 matlab.ui.control.TextArea
        distanceLabel            matlab.ui.control.Label
        BaudRateDropDownLabel    matlab.ui.control.Label
        BaudRateDropDown         matlab.ui.control.DropDown
        TextArea_2               matlab.ui.control.TextArea
        powerLabel               matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        Lamp                     matlab.ui.control.Lamp
        thresholdEditFieldLabel  matlab.ui.control.Label
        thresholdEditField       matlab.ui.control.NumericEditField
        TextArea_3               matlab.ui.control.TextArea
        counterLabel             matlab.ui.control.Label
        pauseButton              matlab.ui.control.Button
    end


    properties (Access = public)
        RX_data % 串口接收的数据
        s       %串口设置句柄
        COM     %端口号
        is_open %串口是否打开标志
        BaudRate
        front
        
    end
    methods (Access = public)
    
        function EveBytesAvailableFcn(app,src,event)
%             msgbox('回调成功');
            n=src.BytesAvailable;
            if n>=20&&app.is_open==1
            app.RX_data=fread(src,n,'uint8');
            app.RX_data=dec2hex(app.RX_data);
            Size=20;
            str=ones(1,Size*2);
            for n=1:Size
               str(n*2-1)=app.RX_data(n,1);
               str(n*2)=app.RX_data(n,2);
            end
            pos=min(strfind(str,'90EB'));
            if pos<=10
            power=[str(pos+14) str(pos+15) str(pos+12) str(pos+13) ];
            distance=[str(pos+10) str(pos+11) str(pos+8) str(pos+9) ];
            distance=hex2dec(distance)/9.8;
            y=distance;
            power=hex2dec(power);
            distance=strcat(string(distance), 'cm');
            app.TextArea.Value=string(distance);
            app.TextArea_2.Value=string(power);
            xi=[-5 -5 -5 -5 -5 -5 -5 -5 -5 -5 -5 -5 0 5 5 5 5 5 5 5 5 5 5 5 5];
            yi=[300 275 250 225 200 175 150 125 100 75 50 25 y 25 50 75 100 125 150 175 200 225 250 275 300];
            plot(app.UIAxes,0,y,'o');
            axis(app.UIAxes,[-5 5 0 250]);
            if(y<app.thresholdEditField.Value&&app.front>app.thresholdEditField.Value)
                  app.TextArea_3.Value=string(str2double(app.TextArea_3.Value)+1);
            end
            if(y<app.thresholdEditField.Value)
                app.Lamp.Color='red';
            else
                app.Lamp.Color='green';
            end
             app.front=y;
            end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RX_data=0;
            app.is_open=0;
            app.BaudRate=115200;
            scoms=instrfind;
            if scoms~=0
                stopasync(scoms);
                fclose(scoms);
                delete(scoms);
            end
        end

        % Button pushed function: openserialButton
        function openserialButtonPushed(app, event)
            app.COM=get(app.COMDropDown,'Value');
            if strcmp(get(app.openserialButton,'Text'),'open serial')
                try
                    app.s=serial(app.COM);
                    app.s.DataBits=8;
                    app.s.StopBits=1;
                    app.s.BaudRate=app.BaudRate;
                    app.s.InputBufferSize=1024000;
                    app.s.BytesAvailableFcnMode='byte';
                    app.s.BytesAvailableFcnCount=20;
                    app.s.BytesAvailableFcn={@app.EveBytesAvailableFcn};
                    fopen(app.s);
                    app.is_open=1;
                    app.openserialButton.Text='close serial';    
                    app.TextArea_3.Value=string(0);
                    app.front=0;
                catch err
                    msgbox('open failed');
                end
            else
                try
                    fclose(app.s);
                    delete(app.s);
                    app.is_open=0;
                    app.openserialButton.Text='open serial';
                    app.TextArea.Value=string(0);
                    app.TextArea_2.Value=string(0);
                    app.TextArea_3.Value=string(0);
                    app.Lamp.Color=[0.8 0.8 0.8];
                catch err
                    msgbox('close failed');
                end
            end
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            value = app.COMDropDown.Value;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
           
            
        end

        % Value changed function: BaudRateDropDown
        function BaudRateDropDownValueChanged(app, event)
            app.BaudRate=app.BaudRateDropDown.Value;
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
          
            
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)
            value = app.thresholdEditField.Value;
            
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
             if strcmp(get(app.pauseButton,'Text'),'pause')&&app.is_open==1
                try
                    app.pauseButton.Text='restar';    
                    app.is_open=0;
                catch err
                    msgbox('pause failed');
                end
            else
                try
                    app.pauseButton.Text='pause';
                    app.is_open=1;
                catch err
                    msgbox('restar failed');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.openserialButton = uibutton(app.UIFigure, 'push');
            app.openserialButton.ButtonPushedFcn = createCallbackFcn(app, @openserialButtonPushed, true);
            app.openserialButton.Text = 'open serial';
            app.COMDropDownLabel = uilabel(app.UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.UIFigure);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM8';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.distanceLabel = uilabel(app.UIFigure);
            app.distanceLabel.Text = 'distance';
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {'115200', '9600'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRateDropDownValueChanged, true);
            app.BaudRateDropDown.Value = '115200';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.powerLabel = uilabel(app.UIFigure);
            app.powerLabel.Text = 'power';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'distance plot')
            xlabel(app.UIAxes, 'X/m')
            ylabel(app.UIAxes, 'Y/m')
            app.Lamp = uilamp(app.UIFigure);
            app.thresholdEditFieldLabel = uilabel(app.UIFigure);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.counterLabel = uilabel(app.UIFigure);
            app.counterLabel.Text = 'counter';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radar_uart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end